classdef apneaDetector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        apneaDetectorWindow             matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadEDFMenu                     matlab.ui.container.Menu
        DetectApneasandSleepMenu        matlab.ui.container.Menu
        LoadHumanApneaDetectionMenu     matlab.ui.container.Menu
        LoadHumanSleepScoringMenu       matlab.ui.container.Menu
        SaveAnalysisStateMenu           matlab.ui.container.Menu
        LoadPreviouslyAnalyzedEDFMenu   matlab.ui.container.Menu
        ExportDatatoMatlabStructMenu    matlab.ui.container.Menu
        ClearAppDataMenu                matlab.ui.container.Menu
        CloseMenu                       matlab.ui.container.Menu
        SettingsMenu                    matlab.ui.container.Menu
        ApneaDetectionThresholdMenu     matlab.ui.container.Menu
        xAverageBreathInterval1_5x      matlab.ui.container.Menu
        xAverageBreathIntervalMenu_2    matlab.ui.container.Menu
        UnfilteredSignalYLimitsMenu     matlab.ui.container.Menu
        incrementStd                    matlab.ui.container.Menu
        decrementStd                    matlab.ui.container.Menu
        Default4StdMenu                 matlab.ui.container.Menu
        SummaryMenu                     matlab.ui.container.Menu
        OutputApneaStatsMenu            matlab.ui.container.Menu
        filteredSignalWindow            matlab.ui.control.UIAxes
        unfilteredSignalWindow          matlab.ui.control.UIAxes
        eventInspectorPlotWindow        matlab.ui.control.UIAxes
        AutoApneaTypeButtonGroup        matlab.ui.container.ButtonGroup
        SpontaneousButton               matlab.ui.control.ToggleButton
        PostSighButton                  matlab.ui.control.ToggleButton
        PostSighPlusButton              matlab.ui.control.ToggleButton
        supportingPlot1                 matlab.ui.control.UIAxes
        PlotTypeDropDown_2Label         matlab.ui.control.Label
        supportingPlot1Dropdown         matlab.ui.control.DropDown
        supportingPlot2                 matlab.ui.control.UIAxes
        PlotTypeDropDownLabel           matlab.ui.control.Label
        SupportingPlot2Dropdown         matlab.ui.control.DropDown
        supportingPlot3                 matlab.ui.control.UIAxes
        PlotTypeDropDown_3Label         matlab.ui.control.Label
        supportingPlot3Dropdown         matlab.ui.control.DropDown
        breathClusteringPlot            matlab.ui.control.UIAxes
        ManualApneaAdjustButtonGroup    matlab.ui.container.ButtonGroup
        SpontaneousButton_2             matlab.ui.control.ToggleButton
        PostSighButton_2                matlab.ui.control.ToggleButton
        PostSighPlusButton_2            matlab.ui.control.ToggleButton
        NotEventButton_2                matlab.ui.control.ToggleButton
        ofTextAreaLabel                 matlab.ui.control.Label
        totalNumberOfApneasText         matlab.ui.control.TextArea
        recordLabelTextArea             matlab.ui.control.TextArea
        SelectPlethChannelDropDown      matlab.ui.control.DropDown
        EventSpinnerLabel               matlab.ui.control.Label
        EventSpinner                    matlab.ui.control.Spinner
        EventNotesTextAreaLabel         matlab.ui.control.Label
        EventNotesTextArea              matlab.ui.control.TextArea
        SessionNotesTextAreaLabel       matlab.ui.control.Label
        SessionNotesTextArea            matlab.ui.control.TextArea
        ShowRawSignalButton             matlab.ui.control.StateButton
        Button                          matlab.ui.control.StateButton
        Button_2                        matlab.ui.control.StateButton
        EventInspectorPlotOptionsLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        stdMultiplier = 4; % The multiplier default for setting the yLimits when viewing the unfiltered signal
        defaultSignal = 1;
        filteredSignalYlimScaleFactor = 4; % The multiplier default for setting the yLimits when viewing the filtered signal
               
        % plotting cutoffs
        highCutIEI = 6;
        tidalVolCut = 3000;
        highCutAmplitudes = 25;


        % initialise some variables that don't have starting values
        filespec;
        header;
        targetSignal;
        breathSignal;
        std_breathSignal;
        fs;
        totalSeconds;
        totalMinutes;
        timeSignal;
        signalSize;
        filteredSignal;
        noiseIndicator;
        f1;
        output;
        whichEvent;
        eventInspectorBuffer;
        
        amplitudes;
        peaks;
        starts;
        ends;
        ideal;
        oldIdeal;
        BPM;
        iei;
        durations;
        meanTidalVolume;
        tidalVolume;
        mistakes;
        
        potentialHypopneas;
        
        averageBreathDuration;

        theseAreMissedBreaths;
        apneaDurations;
        apneaIndex;
        howManyApneas;

        spontaneousApneaIndex;
        spontaneousApneaDurations;
        postSighApneaIndex;
        postSighApneaDurations;
        nSpontaneousApneas;
        nPostSighApneas;
        
        spontaneousStarts ;
        postSighStarts;
        apneaStarts;
        postSighPlusDurationCrit;
        postSighPlusStarts;
        howManyPostSighPlus;
        howManySpontaneous;
        howManyPostSigh;
            
        wakeStarts;
        wakeEnds;
        wakeDurations;
        
        humanScore;
        humanScoreFilesep;
        humanSleepScore;
        humanSleepScoreFilesep;
        
        tp_apneaScores;
        fp_apneaScores;
        fn_apneaScores;
        tn_apneaScores;
        confusionMat_apneaScores;
        
        tempMarkerFiltered;
        tempMarkerCluster;
  
        
    end
    
    methods (Access = public)
        
        function plotUnfilteredSignalWindow(app)
            pause(1)    
            plot(app.unfilteredSignalWindow,  app.timeSignal,  app.breathSignal - mean( app.breathSignal), 'k')
            pause(1)
            app.unfilteredSignalWindow.YLim = [- app.std_breathSignal *  app.stdMultiplier,  app.std_breathSignal *  app.stdMultiplier];
            app.unfilteredSignalWindow.XLim = [0 max( app.timeSignal)];
            % removeToolbarExplorationButtons(app.unfilteredSignalWindow) % this didn'ts seem to work but should have
            
            app.unfilteredSignalWindow.Toolbar.Visible = 'off';
            
        end
    
        function importFile(app)
            [ app.header,  app.breathSignal] = edfread( app.filespec, 'targetSignals', app.targetSignal); % can add 'targetSignals',targetSignal to only important relevant siganls
            app.std_breathSignal = std( app.breathSignal);
        end
    
        function plotFilteredSingal(app)
            plot(app.filteredSignalWindow, app.timeSignal, app.filteredSignal, 'k');
            app.filteredSignalWindow.XLim = [0 max(app.timeSignal)];
            app.filteredSignalWindow.XLim = [-10 10];      
        end
    
        function plotFilteredSignalAndApneas(app)
            
            % plot the filtered signal
            p1 = plot(app.filteredSignalWindow, app.timeSignal, app.filteredSignal, 'k');
            hold(app.filteredSignalWindow, 'on')
            
            % plot the ideal signal
            p2 = plot(app.filteredSignalWindow, app.timeSignal, app.output.ideal, 'c');        
            
            % plot breath amplitudes
            p3 = plot(app.filteredSignalWindow, app.output.peaks / app.fs, app.output.amplitudes, '.c', 'MarkerSize', 10);
                    
            % plot the breaths with < 60% tidal volume from the median breath, hypopneas
            p5 = plot(app.filteredSignalWindow, app.output.peaks(app.output.potentialHypopneas) / app.fs, app.output.amplitudes(app.output.potentialHypopneas), 'b.', 'MarkerSize', 8);
            
            % plot clustered Sighs
            p4 = plot(app.filteredSignalWindow, app.timeSignal(app.output.peaks(app.output.postSighApneaIndex)), app.output.amplitudes(app.output.postSighApneaIndex), 'or');
            
            app.filteredSignalWindow.XLim = [0 max(app.timeSignal)];
            %app.filteredSignalWindow.YLim = [-std(app.filteredSignalWindow) * app.filteredSignalYlimScaleFactor, std(app.filteredSignalWindow) * app.filteredSignalYlimScaleFactor];
            app.filteredSignalWindow.YLim = [-10 10];
            
            % loop through all of the apneas and plot them on the graph
            for i = 1:size(app.apneaDurations, 2)
                temp = app.output.peaks(app.output.apneaIndex(i)):(app.output.peaks(app.output.apneaIndex(i)) + (app.output.apneaDurations(i) * app.fs));
                plot(app.filteredSignalWindow, app.timeSignal(temp), ones(length(temp), 1) * 5, 'r', 'linewidth', 15)
            end
            %msp = plot(app.timeSignal(temp), ones(length(temp), 1) * 5, 'r', 'linewidth', 5); % for legend
            
            if isfield(app, 'humanScore')
                for i = 1:size(app.humanScore, 1)
                    startTime = app.humanScore.StartTime(i);
                    endTime = startTime + app.humanScore.Duration(i);
                    temp = startTime:endTime;
                    plot(app.filteredSignalWindow, temp, ones(length(temp), 1) * 6, 'm', 'linewidth', 15)
                end
            end
            
            %plotSleepPeriods
            
            for jj = 1:length(app.output.wakeStarts)
                temp = app.output.wakeStarts(jj):app.output.wakeStarts(jj)+app.output.wakeDurations(jj);
                plot(app.filteredSignalWindow, app.timeSignal(temp), ones(length(temp), 1) * -3, 'g', 'linewidth', 10)
            end
            %awd = plot(output2.timeSignal(temp), ones(length(temp), 1) * -3, 'b', 'linewidth', 5);

            %dataCursorMode(app.filteredSignalWindow, 'off')
            
            %{
            if exist('humanScore1') & exist('humanSleepScore')
                legend([p1, p2, p3, p4, p5, msp, hsp, psp, awd], {'Filtered Signal', 'Breaths', 'Breath Peaks', 'Apneas w/ Sighs', 'Hypopneas', 'Machine Apneas', 'Human Apneas', 'Sleep', 'Automated Wake'})
            elseif ~exist('humanScore1') & exist('humanSleepScore')
                legend([p1, p2, p3, p4, p5, msp, psp, awd], {'Filtered Signal', 'Breaths', 'Breath Peaks', 'Apneas w/ Sighs', 'Hypopneas', 'Machine Apneas', 'Sleep', 'Automated Wake'})
            elseif exist('humanScore1') & ~exist('humanSleepScore')
                legend([p1, p2, p3, p4, p5, msp, hsp, awd], {'Filtered Signal', 'Breaths', 'Breath Peaks', 'Apneas w/ Sighs', 'Hypopneas', 'Machine Apneas', 'Human Apneas', 'Automated Wake'})
            else
                legend([p1, p2, p3, p4, p5, msp, awd], {'Filtered Signal', 'Breaths', 'Breath Peaks', 'Apneas w/ Sighs', 'Hypopneas', 'Machine Apneas', 'Automated Wake'})
            end
        
            %}
            
        end
    
        function plotBreathClustering(app)
            
            plot(app.breathClusteringPlot, app.output.iei(~app.output.theseAreMissedBreaths(1:end-1)), app.output.amplitudes(~app.output.theseAreMissedBreaths(1:end-1)), 'b.', 'MarkerSize', 5) % these are non-apnea related breaths
            hold(app.breathClusteringPlot, 'on')
            grid(app.breathClusteringPlot, 'on')
            
            app.breathClusteringPlot.XLim = [0, app.highCutIEI];
            app.breathClusteringPlot.YLim = [0, app.highCutAmplitudes];
                
            plot(app.breathClusteringPlot, app.output.iei(app.output.spontaneousApneaIndex), app.output.amplitudes(app.output.spontaneousApneaIndex), 'k.', 'MarkerSize', 5)
            plot(app.breathClusteringPlot, app.output.iei(app.output.postSighApneaIndex), app.output.amplitudes(app.output.postSighApneaIndex), 'r.', 'MarkerSize', 5)
            
            legend(app.breathClusteringPlot, {'nonApneas', 'noSighApnea', 'sighApnea'})
            %datacursormode(app.breathClusteringPlot, 'off')

      
        end
    
        function plotEventInspector(app)            
            temp = app.output.peaks(app.output.apneaIndex(app.whichEvent)):(app.output.peaks(app.output.apneaIndex(app.whichEvent)) + (app.output.apneaDurations(app.whichEvent) * app.fs));
            plotStart = temp(1) - app.eventInspectorBuffer;
            plotEnd = plotStart + (app.eventInspectorBuffer * 5);
            
            hold(app.eventInspectorPlotWindow, "on")
            
            % plot the raw signal in the event inspector if requested
            if app.ShowRawSignalButton.Value
                plot(app.eventInspectorPlotWindow, app.timeSignal(plotStart:plotEnd), app.unfilteredSignal(plotStart:plotEnd), 'm')
            end
                        
            % plot the filtered Signal
            plot(app.eventInspectorPlotWindow, app.timeSignal(plotStart:plotEnd), app.filteredSignal(plotStart:plotEnd), 'k')
            
            % plot the ideal signal
            p2 = plot(app.eventInspectorPlotWindow, app.timeSignal(plotStart:plotEnd), app.output.ideal(plotStart:plotEnd), 'c');        
            
            % plot breath amplitudes
            p3 = plot(app.eventInspectorPlotWindow, app.output.peaks / app.fs, app.output.amplitudes, '.c', 'MarkerSize', 10);
            
            % plot the breaths with < 60% tidal volume from the median breath, hypopneas
            p5 = plot(app.eventInspectorPlotWindow, app.output.peaks(app.output.potentialHypopneas) / app.fs, app.output.amplitudes(app.output.potentialHypopneas), 'b.', 'MarkerSize', 8);
            
            % plot clustered Sighs
            p4 = plot(app.eventInspectorPlotWindow, app.timeSignal(app.output.peaks(app.output.postSighApneaIndex)), app.output.amplitudes(app.output.postSighApneaIndex), 'ob');
            
            app.eventInspectorPlotWindow.XLim = [app.timeSignal(plotStart), app.timeSignal(plotEnd)];
            app.eventInspectorPlotWindow.YLim = [-10 10];
            
            
            % loop through all of the apneas and plot them on the graph
            if app.whichEvent > 1 && app.whichEvent < app.output.howManyApneas
                rangeForPlotting = app.whichEvent-1:app.whichEvent+1;
            elseif app.whichEvent < 2
                rangeForPlotting = app.whichEvent:app.whichEvent+1;
            elseif app.whichEvent == app.output.howManyApneas
                rangeForPlotting = app.whichEvent-1:app.whichEvent;
            end
            
                 
            for i = rangeForPlotting %1:size(app.output.apneaDurations, 2)
                temp = app.output.peaks(app.output.apneaIndex(i)):(app.output.peaks(app.output.apneaIndex(i)) + (app.output.apneaDurations(i) * app.fs));
                plot(app.eventInspectorPlotWindow, app.timeSignal(temp), ones(length(temp), 1) * 5, 'r', 'linewidth', 15)
                plot(app.eventInspectorPlotWindow, app.timeSignal(temp(1)), ones(length(temp(1)), 1) * 9, '.r', 'linewidth', 20)
            end
            
            % now mark where this event occurs on the main plot window and in the clustering batch.
            app.tempMarkerFiltered = plot(app.filteredSignalWindow, app.timeSignal(app.output.peaks(app.output.apneaIndex(app.whichEvent))), 9, '.r', 'markersize', 35);
            app.tempMarkerCluster = plot(app.breathClusteringPlot, app.output.iei(app.output.apneaIndex(app.whichEvent)) , app.output.amplitudes(app.output.apneaIndex(app.whichEvent)), '.r', 'markersize', 35); 
            
            % add the apnea type labeling 
            if app.output.typeOfApnea(app.whichEvent) == 1
                app.SpontaneousButton.Value = true;
                %app.PostSighButton.Value = false;
                %app.PostSighPlusButton.Value = false;
            elseif app.output.typeOfApnea(app.whichEvent) == 2
                %app.SpontaneousButton.Value = false;
                app.PostSighButton.Value = true;
                %app.PostSighPlusButton.Value = false;
            elseif app.output.typeOfApnea(app.whichEvent) == 3
                %app.SpontaneousButton.Value = false;
                %app.PostSighButton.Value = false;
                app.PostSighPlusButton.Value = true;
            end                
            
            if app.output.manualTypeOfApnea(app.whichEvent) == 1
                app.SpontaneousButton_2.Value = true;
            elseif app.output.manualTypeOfApnea(app.whichEvent) == 2
                app.PostSighButton_2.Value = true;
            elseif app.output.manualTypeOfApnea(app.whichEvent) == 3
                app.PostSighPlusButton_2.Value = true;
            elseif app.output.manualTypeOfApnea(app.whichEvent) == 4
                app.NotEventButton_2.Value = true;
            end 
                        
            % load notes data
            app.EventNotesTextArea.Value = app.output.apneaNotes(app.whichEvent);
            
            % now plot the human marked apneas if loaded
            if isfield(app, 'humanScore')
                for i = 1:size(app.humanScore, 1)
                    startTime = app.humanScore.StartTime(i);
                    endTime = startTime + app.humanScore.Duration(i);
                    temp = startTime:endTime;
                    plot(app.filteredSignalWindow, temp, ones(length(temp), 1) * 6, 'm', 'linewidth', 15)
                end
            end


        end
    
        function plotSummaryDataInSupportWindow(app, windowNumber)
            if windowNumber == 1
                temp = app.supportingPlot1;
            elseif windowNumber == 2
                temp = app.supportingPlot2;
            else
                temp = app.supportingPlot3;
            end
            text(temp, 0.1,0.8, strcat("n Spontaneous Apneas: ", num2str(app.output.nSpontaneousApneas)));
            text(temp, 0.1,0.7, strcat("S Apneas/Hour Sleep: ", num2str(app.output.nSpontaneousApneas / app.output.totalHoursSleeping)));
            text(temp, 0.1,0.6, strcat("n Post Sigh Apneas: ", num2str(app.output.nPostSighApneas)));
            text(temp, 0.1,0.5, strcat("PS Apneas/Hour Sleep: ", num2str(app.output.nPostSighApneas / app.output.totalHoursSleeping)));
            text(temp, 0.1,0.4, strcat("n Post Sigh Plus Apneas: ", num2str(app.output.nPostSighPlus)));
            text(temp, 0.1,0.3, strcat("PSP Apneas/Hour Sleep: ", num2str(app.output.nPostSighPlus / app.output.totalHoursSleeping)));
            axis(temp, 'off')
           
        end

        function plotApneaComparisonInSupportWindow(app, windowNumber)
        if windowNumber == 1
            temp = app.supportingPlot1;
        elseif windowNumber == 2
            temp = app.supportingPlot2;
        else
            temp = app.supportingPlot3;
        end
        
            text(temp, 0,0.8, strcat("True Positives: ", num2str(app.confusionMat_apneaScores(1))));
            text(temp, 0,0.6, strcat("False Positives: ", num2str(app.confusionMat_apneaScores(3))));
            text(temp, 0,0.4, strcat("False Negatives: ", num2str(app.confusionMat_apneaScores(2))));
            text(temp, 0,0.2, strcat("True Negatives: ", num2str(app.confusionMat_apneaScores(4))));
            axis(temp, 'off')

            %heatmap(temp, {'no', 'yes'}, {'no', 'yes'}, app.confusionMat_apneaScores);
            title(temp, 'Apnea Scoreing: Human vs. Machine')
            %xlabel(temp, 'H1 Apneas')
            %ylabel(temp, 'Machine Apneas')
            
        end
    end


    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: LoadEDFMenu
        function openFile(app, event)
            
            webWindows = matlab.internal.webwindowmanager.instance.findAllWebwindows(); %#ok<NASGU>
            webWindows = matlab.internal.webwindowmanager.instance.windowList;
            for ii = 1:length(webWindows)
                webWindow = webWindows(ii);
                if strcmp( webWindow.Title, "apneaDetector" )
                    break
                end
            end
            
            [file,  path] = uigetfile('*.edf', 'Pick an EDF file');
                    
            
            webWindow.setAlwaysOnTop(true);
            webWindow.setAlwaysOnTop(false);

            app.apneaDetectorWindow.Visible = 'off'; app.apneaDetectorWindow.Visible = 'on';
            figure(app.apneaDetectorWindow)
            
            app.filespec = strcat( path,  file);
            
            % import the file header
            [ app.header] = edfread( app.filespec);
            app.SelectPlethChannelDropDown.Items =  app.header.label;
            app.SelectPlethChannelDropDown.Value =  app.header.label(app.defaultSignal);
            app.targetSignal = app.SelectPlethChannelDropDown.Value;

            % import the file, this should be an EDF file
            importFile(app)
            
            % set the sample rate
            app.fs =  app.header.frequency(1); % this sample rate gives shows 120 BPM for a rat which is about what we are looking for and matches the timing from the excel files that Jonathan provided.
            
            % establish the time signal
            app.totalSeconds = length( app.breathSignal) /  app.fs;
            app.totalMinutes =  app.totalSeconds / 60;
            app.timeSignal = 0:( app.totalSeconds/length( app.breathSignal)): app.totalSeconds;
            app.timeSignal =  app.timeSignal(2:end);

            % possibly open up a popup here so that users can select the correct channel?
            
            
            % plot the original signal
            plotUnfilteredSignalWindow(app)
                  
            % put a label for the record in the bottom panel
            app.recordLabelTextArea.Value =  app.filespec;
            

        end

        % Value changed function: SelectPlethChannelDropDown
        function SelectPlethChannelDropDownValueChanged(app, event)
            app.targetSignal = app.SelectPlethChannelDropDown.Value;
            pause(1)
            importFile(app)
            pause(1)
            plotUnfilteredSignalWindow(app)
            %plotFilteredSingal(app)
            
        end

        % Menu selected function: Default4StdMenu
        function Default4StdMenuSelected(app, event)
            app.stdMultiplier = 4;
            plotUnfilteredSignalWindow(app)
        end

        % Menu selected function: incrementStd
        function incrementStdSelected(app, event)
            app.stdMultiplier =  app.stdMultiplier + 1;
            plotUnfilteredSignalWindow(app)
        end

        % Menu selected function: decrementStd
        function decrementStdMenuSelected(app, event)
            app.stdMultiplier =  app.stdMultiplier - 1;
            plotUnfilteredSignalWindow(app)
        end

        % Menu selected function: DetectApneasandSleepMenu
        function DetectApneasandSleepMenuSelected(app, event)
            
            % first clear the plot data
            cla(app.filteredSignalWindow)
            cla(app.eventInspectorPlotWindow)
            cla(app.breathClusteringPlot)
            cla(app.supportingPlot1)
            cla(app.supportingPlot2)
            cla(app.supportingPlot3)

            % tell user to wait
            text(app.filteredSignalWindow, .2, .5, "Please Wait While Breaths and Apneas are Detected.", 'FontSize', 25)

            app.filteredSignal = filterAndNormalizePlethSignal(app.breathSignal, app.fs);
            
            app.output = detectBreathsApneasAndSleepFromPleth(app.filteredSignal, app.fs);
                        
            plotFilteredSignalAndApneas(app)
            
            plotBreathClustering(app)
            
            plotSummaryDataInSupportWindow(app, 1)
           
            % set some variables for the event Inspector
            app.eventInspectorBuffer = app.fs * 7;
            app.whichEvent = 1;
            
            % initialize manual apnea change list
            app.output.manualTypeOfApnea = app.output.typeOfApnea;
            
            % initialize event notes space
            for i = 1:app.output.howManyApneas
                app.output.apneaNotes{i} = '';
            end
            
            plotEventInspector(app)
            
            app.totalNumberOfApneasText.Value = num2str(app.output.howManyApneas);
            app.EventSpinner.Value = app.whichEvent;
            app.EventSpinner.Limits = [1 app.output.howManyApneas];
    
            % enable some menu items now that we've detected apneas
            app.LoadHumanApneaDetectionMenu.Enable = 'on';
            app.LoadHumanSleepScoringMenu.Enable = 'on';
            app.SaveAnalysisStateMenu.Enable = 'on';
            app.ClearAppDataMenu.Enable = 'on';
            
            % populate list for supporting plot dropdowns
            app.supportingPlot1Dropdown.Items = {'Breath Summary', 'Apnea Summary', 'SleepScore Comp', 'ApneaScore Comp'};
            
            
        end

        % Menu selected function: LoadHumanApneaDetectionMenu
        function LoadHumanApneaDetectionMenuSelected(app, event)
            [file,  path] = uigetfile('*.csv', 'Pick a Sleep Score File');
            app.apneaDetectorWindow.Visible = 'off'; app.apneaDetectorWindow.Visible = 'on';
            figure(app.apneaDetectorWindow)

            app.humanScoreFilesep = strcat( path,  file);
            
            app.humanScore = importfile_humanApneaScores(app.humanScoreFilesep);
            app.humanScore = app.humanScore(app.humanScore.Duration >= app.output.apneaThreshold, :); % only scores above the same apnea threshold as the computer set.
            
            % compare automated and human apneas
            compareApneaScores(app)
            
            % plot everything
            plotFilteredSignalAndApneas(app)
            plotEventInspector(app)
            plotApneaComparisonInSupportWindow(app, 2)
            
        end

        % Value changed function: EventSpinner
        function EventSpinnerValueChanged(app, event)
            app.whichEvent = app.EventSpinner.Value;
            delete(app.tempMarkerFiltered);
            delete(app.tempMarkerCluster);

            plotEventInspector(app);
            
        end

        % Selection changed function: ManualApneaAdjustButtonGroup
        function ManualApneaAdjustButtonGroupSelectionChanged(app, event)
            temp = app.ManualApneaAdjustButtonGroup.SelectedObject;
            
            if strcmp(temp.Text, 'Spontaneous')
                app.output.manualTypeOfApnea(app.whichEvent) = 1;
            elseif strcmp(temp.Text, 'Post Sigh')
                app.output.manualTypeOfApnea(app.whichEvent) = 2;
            elseif strcmp(temp.Text, 'Post Sigh Plus')
                app.output.manualTypeOfApnea(app.whichEvent) = 3;
            elseif strcmp(temp.Text, 'Not Event')
                app.output.manualTypeOfApnea(app.whichEvent) = 4;
            end

        end

        % Menu selected function: SaveAnalysisStateMenu
        function SaveAnalysisStateMenuSelected(app, event)
            cd(fileparts(app.filespec)); % change directory to current working file
            uisave('app') % prompt user to save file
            
            
        end

        % Menu selected function: LoadPreviouslyAnalyzedEDFMenu
        function LoadPreviouslyAnalyzedEDFMenuSelected(app, event)
            [file,  path] = uigetfile('*.mat', 'Pick a Scored Apnea Mat File.');
                       
            cla(app.filteredSignalWindow)
            cla(app.unfilteredSignalWindow)
            cla(app.breathClusteringPlot)
            
            load(strcat(path, file), '-mat');
            
            %refresh(app.filteredSignalWindow)
            %refresh(app.unfilteredSignalWindow)
            %refresh(app.breathClusteringPlot)

            
            plotFilteredSignalAndApneas(app)
            plotUnfilteredSignalWindow(app)
            plotBreathClustering(app)
        end

        % Value changed function: EventNotesTextArea
        function EventNotesTextAreaValueChanged(app, event)
            app.output.apneaNotes(app.whichEvent) = app.EventNotesTextArea.Value;
            
        end

        % Value changed function: supportingPlot1Dropdown
        function supportingPlot1DropdownValueChanged(app, event)
            value = app.supportingPlot1Dropdown.Value; % xxx
            
        end

        % Menu selected function: ExportDatatoMatlabStructMenu
        function ExportDatatoMatlabStructMenuSelected(app, event)
            apneaDetectorOutput = app.output;
            uisave('apneaDetectorOutput');
            
        end

        % Value changed function: SessionNotesTextArea
        function SessionNotesTextAreaValueChanged(app, event)
            app.output.sessionNotes = app.SessionNotesTextArea.Value;
            
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            closereq
        end

        % Value changed function: ShowRawSignalButton
        function ShowRawSignalButtonValueChanged(app, event)
                plotEventInspector(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.apneaDetectorWindow = uifigure('Visible', 'off');
            app.apneaDetectorWindow.Name = 'Apnea Detector';
            app.FileMenu = uimenu(app.apneaDetectorWindow);
            app.FileMenu.Text = 'File';
            app.LoadEDFMenu = uimenu(app.FileMenu);
            app.LoadEDFMenu.MenuSelectedFcn = createCallbackFcn(app, @openFile, true);
            app.LoadEDFMenu.Text = 'Load EDF';
            app.DetectApneasandSleepMenu = uimenu(app.FileMenu);
            app.DetectApneasandSleepMenu.MenuSelectedFcn = createCallbackFcn(app, @DetectApneasandSleepMenuSelected, true);
            app.DetectApneasandSleepMenu.Text = 'Detect Apneas and Sleep';
            app.LoadHumanApneaDetectionMenu = uimenu(app.FileMenu);
            app.LoadHumanApneaDetectionMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadHumanApneaDetectionMenuSelected, true);
            app.LoadHumanApneaDetectionMenu.Enable = 'off';
            app.LoadHumanApneaDetectionMenu.Text = 'Load Human Apnea Detection';
            app.LoadHumanSleepScoringMenu = uimenu(app.FileMenu);
            app.LoadHumanSleepScoringMenu.Enable = 'off';
            app.LoadHumanSleepScoringMenu.Text = 'Load Human Sleep Scoring';
            app.SaveAnalysisStateMenu = uimenu(app.FileMenu);
            app.SaveAnalysisStateMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAnalysisStateMenuSelected, true);
            app.SaveAnalysisStateMenu.Enable = 'off';
            app.SaveAnalysisStateMenu.Text = 'Save Analysis State';
            app.LoadPreviouslyAnalyzedEDFMenu = uimenu(app.FileMenu);
            app.LoadPreviouslyAnalyzedEDFMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadPreviouslyAnalyzedEDFMenuSelected, true);
            app.LoadPreviouslyAnalyzedEDFMenu.Text = 'Load Previously Analyzed EDF';
            app.ExportDatatoMatlabStructMenu = uimenu(app.FileMenu);
            app.ExportDatatoMatlabStructMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportDatatoMatlabStructMenuSelected, true);
            app.ExportDatatoMatlabStructMenu.Text = 'Export Data to Matlab Struct';
            app.ClearAppDataMenu = uimenu(app.FileMenu);
            app.ClearAppDataMenu.Enable = 'off';
            app.ClearAppDataMenu.Text = 'Clear App Data';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Text = 'Close';
            app.SettingsMenu = uimenu(app.apneaDetectorWindow);
            app.SettingsMenu.Text = 'Settings';
            app.ApneaDetectionThresholdMenu = uimenu(app.SettingsMenu);
            app.ApneaDetectionThresholdMenu.Text = 'Apnea Detection Threshold';
            app.xAverageBreathInterval1_5x = uimenu(app.ApneaDetectionThresholdMenu);
            app.xAverageBreathInterval1_5x.Text = '1.5x Average Breath Interval';
            app.xAverageBreathIntervalMenu_2 = uimenu(app.ApneaDetectionThresholdMenu);
            app.xAverageBreathIntervalMenu_2.Text = '2x Average Breath Interval';
            app.UnfilteredSignalYLimitsMenu = uimenu(app.SettingsMenu);
            app.UnfilteredSignalYLimitsMenu.Text = 'Unfiltered Signal Y Limits';
            app.incrementStd = uimenu(app.UnfilteredSignalYLimitsMenu);
            app.incrementStd.MenuSelectedFcn = createCallbackFcn(app, @incrementStdSelected, true);
            app.incrementStd.Text = '+1 Std';
            app.decrementStd = uimenu(app.UnfilteredSignalYLimitsMenu);
            app.decrementStd.MenuSelectedFcn = createCallbackFcn(app, @decrementStdMenuSelected, true);
            app.decrementStd.Text = '-1 Std';
            app.Default4StdMenu = uimenu(app.UnfilteredSignalYLimitsMenu);
            app.Default4StdMenu.MenuSelectedFcn = createCallbackFcn(app, @Default4StdMenuSelected, true);
            app.Default4StdMenu.Text = 'Default (4 Std)';
            app.SummaryMenu = uimenu(app.apneaDetectorWindow);
            app.SummaryMenu.Enable = 'off';
            app.SummaryMenu.Text = 'Summary';
            app.OutputApneaStatsMenu = uimenu(app.SummaryMenu);
            app.OutputApneaStatsMenu.Text = 'Output Apnea Stats';
            app.filteredSignalWindow = uiaxes(app.apneaDetectorWindow);
            title(app.filteredSignalWindow, 'Filtered Signal and Apnea Detection')
            xlabel(app.filteredSignalWindow, 'Time (s)')
            ylabel(app.filteredSignalWindow, 'Pleth Amlitude')
            app.unfilteredSignalWindow = uiaxes(app.apneaDetectorWindow);
            title(app.unfilteredSignalWindow, 'Unfiltered Signal')
            xlabel(app.unfilteredSignalWindow, 'Time (s)')
            ylabel(app.unfilteredSignalWindow, 'Pleth Amplitude')
            app.eventInspectorPlotWindow = uiaxes(app.apneaDetectorWindow);
            title(app.eventInspectorPlotWindow, 'Event Inspector')
            xlabel(app.eventInspectorPlotWindow, 'Time (s)')
            ylabel(app.eventInspectorPlotWindow, 'Pleth Amlitdue')
            app.AutoApneaTypeButtonGroup = uibuttongroup(app.apneaDetectorWindow);
            app.AutoApneaTypeButtonGroup.Title = 'Auto Apnea Type';
            app.SpontaneousButton = uitogglebutton(app.AutoApneaTypeButtonGroup);
            app.SpontaneousButton.Text = 'Spontaneous';
            app.SpontaneousButton.Value = true;
            app.PostSighButton = uitogglebutton(app.AutoApneaTypeButtonGroup);
            app.PostSighButton.Text = 'Post Sigh';
            app.PostSighPlusButton = uitogglebutton(app.AutoApneaTypeButtonGroup);
            app.PostSighPlusButton.Text = 'Post Sigh Plus';
            app.supportingPlot1 = uiaxes(app.apneaDetectorWindow);
            title(app.supportingPlot1, 'Title')
            xlabel(app.supportingPlot1, 'X')
            ylabel(app.supportingPlot1, 'Y')
            app.PlotTypeDropDown_2Label = uilabel(app.apneaDetectorWindow);
            app.PlotTypeDropDown_2Label.Text = 'Plot Type';
            app.supportingPlot1Dropdown = uidropdown(app.apneaDetectorWindow);
            app.supportingPlot1Dropdown.ValueChangedFcn = createCallbackFcn(app, @supportingPlot1DropdownValueChanged, true);
            app.supportingPlot2 = uiaxes(app.apneaDetectorWindow);
            title(app.supportingPlot2, 'Title')
            xlabel(app.supportingPlot2, 'X')
            ylabel(app.supportingPlot2, 'Y')
            app.PlotTypeDropDownLabel = uilabel(app.apneaDetectorWindow);
            app.PlotTypeDropDownLabel.Text = 'Plot Type';
            app.SupportingPlot2Dropdown = uidropdown(app.apneaDetectorWindow);
            app.supportingPlot3 = uiaxes(app.apneaDetectorWindow);
            title(app.supportingPlot3, 'Title')
            xlabel(app.supportingPlot3, 'X')
            ylabel(app.supportingPlot3, 'Y')
            app.PlotTypeDropDown_3Label = uilabel(app.apneaDetectorWindow);
            app.PlotTypeDropDown_3Label.Text = 'Plot Type';
            app.supportingPlot3Dropdown = uidropdown(app.apneaDetectorWindow);
            app.breathClusteringPlot = uiaxes(app.apneaDetectorWindow);
            title(app.breathClusteringPlot, 'Breath Clustering')
            xlabel(app.breathClusteringPlot, 'Inter-event Interval (s)')
            ylabel(app.breathClusteringPlot, 'Breath Amplitdues')
            app.ManualApneaAdjustButtonGroup = uibuttongroup(app.apneaDetectorWindow);
            app.ManualApneaAdjustButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ManualApneaAdjustButtonGroupSelectionChanged, true);
            app.ManualApneaAdjustButtonGroup.Title = 'Manual Apnea Adjust';
            app.SpontaneousButton_2 = uitogglebutton(app.ManualApneaAdjustButtonGroup);
            app.SpontaneousButton_2.Text = 'Spontaneous';
            app.SpontaneousButton_2.Value = true;
            app.PostSighButton_2 = uitogglebutton(app.ManualApneaAdjustButtonGroup);
            app.PostSighButton_2.Text = 'Post Sigh';
            app.PostSighPlusButton_2 = uitogglebutton(app.ManualApneaAdjustButtonGroup);
            app.PostSighPlusButton_2.Text = 'Post Sigh Plus';
            app.NotEventButton_2 = uitogglebutton(app.ManualApneaAdjustButtonGroup);
            app.NotEventButton_2.Text = 'Not Event';
            app.ofTextAreaLabel = uilabel(app.apneaDetectorWindow);
            app.ofTextAreaLabel.Text = 'of';
            app.totalNumberOfApneasText = uitextarea(app.apneaDetectorWindow);
            app.totalNumberOfApneasText.Editable = 'off';
            app.recordLabelTextArea = uitextarea(app.apneaDetectorWindow);
            app.SelectPlethChannelDropDown = uidropdown(app.apneaDetectorWindow);
            app.SelectPlethChannelDropDown.Items = {'Ch 1', 'Ch 2', 'Ch 3'};
            app.SelectPlethChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectPlethChannelDropDownValueChanged, true);
            app.SelectPlethChannelDropDown.Value = 'Ch 1';
            app.EventSpinnerLabel = uilabel(app.apneaDetectorWindow);
            app.EventSpinnerLabel.Text = 'Event #';
            app.EventSpinner = uispinner(app.apneaDetectorWindow);
            app.EventSpinner.ValueChangedFcn = createCallbackFcn(app, @EventSpinnerValueChanged, true);
            app.EventNotesTextAreaLabel = uilabel(app.apneaDetectorWindow);
            app.EventNotesTextAreaLabel.Text = 'Event Notes';
            app.EventNotesTextArea = uitextarea(app.apneaDetectorWindow);
            app.EventNotesTextArea.ValueChangedFcn = createCallbackFcn(app, @EventNotesTextAreaValueChanged, true);
            app.SessionNotesTextAreaLabel = uilabel(app.apneaDetectorWindow);
            app.SessionNotesTextAreaLabel.Text = 'Session Notes';
            app.SessionNotesTextArea = uitextarea(app.apneaDetectorWindow);
            app.SessionNotesTextArea.ValueChangedFcn = createCallbackFcn(app, @SessionNotesTextAreaValueChanged, true);
            app.ShowRawSignalButton = uibutton(app.apneaDetectorWindow, 'state');
            app.ShowRawSignalButton.ValueChangedFcn = createCallbackFcn(app, @ShowRawSignalButtonValueChanged, true);
            app.ShowRawSignalButton.Text = 'Show Raw Signal';
            app.EventInspectorPlotOptionsLabel = uilabel(app.apneaDetectorWindow);
            app.EventInspectorPlotOptionsLabel.Text = 'Event Inspector Plot Options';
            app.Button = uibutton(app.apneaDetectorWindow, 'state');
            app.Button.Text = '';
            app.Button_2 = uibutton(app.apneaDetectorWindow, 'state');
            app.Button_2.Text = '';
            % Show the figure after all components are created
            app.apneaDetectorWindow.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = apneaDetector

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.apneaDetectorWindow)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.apneaDetectorWindow)
        end
    end
end